classdef SymbolRecognizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PROCESSEDIMAGELabel  matlab.ui.control.Label
        ORIGINALIMAGELabel   matlab.ui.control.Label
        PROJECTSYMBOLRECOGNITIONUSINGMATLABLabel  matlab.ui.control.Label
        Image2               matlab.ui.control.Image
        RESETLabel           matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        GRAPHICERAHILLUNIVERSITYDEHRADUNLabel  matlab.ui.control.Label
        OUTPUTLabel_2        matlab.ui.control.Label
        Image                matlab.ui.control.Image
        OUTPUTLabel          matlab.ui.control.Label
        MORPHOLOGICALLabel   matlab.ui.control.Label
        BINARIZEIMAGELabel   matlab.ui.control.Label
        GRAYSCALELabel       matlab.ui.control.Label
        UPLOADLabel          matlab.ui.control.Label
        output               matlab.ui.control.Button
        reset                matlab.ui.control.Button
        morphological        matlab.ui.control.Button
        binary               matlab.ui.control.Button
        grayscale            matlab.ui.control.Button
        upload               matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: upload
        function uploadPushed(app, event)
          clc;
          global a;
          [filename,pathname] = uigetfile('*.*','Select an image')
          fulname = strcat(pathname,filename)
          a = imread(fulname);
          imshow(a,'Parent',app.UIAxes);
        end

        % Button pushed function: grayscale
        function grayscalePushed(app, event)
            global a;
            global j;
%           j = a;
            j = rgb2gray(a);
            imshow(j,'Parent',app.UIAxes2);
        end

        % Button pushed function: binary
        function binaryPushed(app, event)
            global j;
            global p;
            p = imbinarize(j);
            imshow(p,'Parent',app.UIAxes2);
        end

        % Button pushed function: morphological
        function morphologicalPushed(app, event)
            global p;
            global s;
            s = ~p;
            
            g = strel('disk',3);
            s = imclose(s,g);
            imshow(s,'Parent',app.UIAxes2);
        end

        % Button pushed function: reset
        function resetPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            app.TextArea.Value = '';
            clc;
        end

        % Callback function
        function ButtonPushed(app, event)
       
        end

        % Button pushed function: output
        function outputPushed(app, event)

            global s;

            m = ocr(s);
            value = m.Text;

            disp('OUTPUT TEXT : ');
            disp(m.Text);

            app.TextArea.Value = value;
            figure(app.UIFigure);
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.upload = uibutton(app.UIFigure, 'push');
            app.upload.ButtonPushedFcn = createCallbackFcn(app, @uploadPushed, true);
            app.upload.Icon = 'icon upload.png';
            app.upload.Text = {''; ''};
            app.grayscale = uibutton(app.UIFigure, 'push');
            app.grayscale.ButtonPushedFcn = createCallbackFcn(app, @grayscalePushed, true);
            app.grayscale.Icon = 'icon gray.png';
            app.grayscale.Text = '';
            app.binary = uibutton(app.UIFigure, 'push');
            app.binary.ButtonPushedFcn = createCallbackFcn(app, @binaryPushed, true);
            app.binary.Icon = 'icon binary.png';
            app.binary.Text = '';
            app.morphological = uibutton(app.UIFigure, 'push');
            app.morphological.ButtonPushedFcn = createCallbackFcn(app, @morphologicalPushed, true);
            app.morphological.Icon = 'icon invert.png';
            app.morphological.Text = '';
            app.reset = uibutton(app.UIFigure, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetPushed, true);
            app.reset.Icon = 'icon reset.png';
            app.reset.Text = '';
            app.output = uibutton(app.UIFigure, 'push');
            app.output.ButtonPushedFcn = createCallbackFcn(app, @outputPushed, true);
            app.output.Icon = 'icon output.png';
            app.output.Text = '';
            app.UPLOADLabel = uilabel(app.UIFigure);
            app.UPLOADLabel.Text = 'UPLOAD';
            app.GRAYSCALELabel = uilabel(app.UIFigure);
            app.GRAYSCALELabel.Text = 'GRAY SCALE';
            app.BINARIZEIMAGELabel = uilabel(app.UIFigure);
            app.BINARIZEIMAGELabel.Text = 'BINARIZE IMAGE';
            app.MORPHOLOGICALLabel = uilabel(app.UIFigure);
            app.MORPHOLOGICALLabel.Text = 'MORPHOLOGICAL';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'icon output2.png';
            app.OUTPUTLabel_2 = uilabel(app.UIFigure);
            app.OUTPUTLabel_2.Text = 'OUTPUT';
            app.GRAPHICERAHILLUNIVERSITYDEHRADUNLabel = uilabel(app.UIFigure);
            app.GRAPHICERAHILLUNIVERSITYDEHRADUNLabel.Text = 'GRAPHIC ERA HILL UNIVERSITY, DEHRADUN';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.RESETLabel = uilabel(app.UIFigure);
            app.RESETLabel.Text = 'RESET';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'icon gehu.png';
            app.PROJECTSYMBOLRECOGNITIONUSINGMATLABLabel = uilabel(app.UIFigure);
            app.PROJECTSYMBOLRECOGNITIONUSINGMATLABLabel.Text = 'PROJECT : SYMBOL RECOGNITION USING MATLAB';
            app.ORIGINALIMAGELabel = uilabel(app.UIFigure);
            app.ORIGINALIMAGELabel.Text = 'ORIGINAL IMAGE';
            app.PROCESSEDIMAGELabel = uilabel(app.UIFigure);
            app.PROCESSEDIMAGELabel.Text = 'PROCESSED IMAGE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SymbolRecognizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end