classdef SymbolRecognizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GenerateTextButton        matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        TextAreaLabel             matlab.ui.control.Label
        ExitButton                matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        MorphologicalImageButton  matlab.ui.control.Button
        BinarizeImageButton       matlab.ui.control.Button
        GrayScaleImageButton      matlab.ui.control.Button
        UploadImageButton         matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
          global a;
          [filename,pathname] = uigetfile('*.*','Select an image')
          filename = strcat(pathname,filename)
          a = imread(filename);
          imshow(a,'Parent',app.UIAxes);
        end

        % Button pushed function: GrayScaleImageButton
        function GrayScaleImageButtonPushed(app, event)
            global a;
            global j;
            j = a;
            j = rgb2gray(j);
            imshow(j,'Parent',app.UIAxes2);
        end

        % Button pushed function: BinarizeImageButton
        function BinarizeImageButtonPushed(app, event)
            global j
            global p;
            p = imbinarize(j);
            imshow(p,'Parent',app.UIAxes2);
        end

        % Button pushed function: MorphologicalImageButton
        function MorphologicalImageButtonPushed(app, event)
            global p;
            global s;
            s = ~p;
            g = strel('disk',5);
            s = imclose(s,g);
            imshow(s,'Parent',app.UIAxes2);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            msgbox("THANKS FOR USING SYMBOL RECOGINAZATION BY SHIVAM THAPA");
            pause(3);
            close();
%             close();
        end

        % Button pushed function: GenerateTextButton
        function GenerateTextButtonPushed(app, event)
           global s;
           global p;
           global j;
           m = ocr(p);
           disp(m.Text);
           c = insertObjectAnnotation(j,"rectangle",m.WordBoundingBoxes,m.WordConfidences);
           imshow(c,'Parent',app.UIAxes2);
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Original Image'; ''})
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Pre-Processed Image')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.GrayScaleImageButton = uibutton(app.UIFigure, 'push');
            app.GrayScaleImageButton.ButtonPushedFcn = createCallbackFcn(app, @GrayScaleImageButtonPushed, true);
            app.GrayScaleImageButton.Text = 'Gray Scale Image';
            app.BinarizeImageButton = uibutton(app.UIFigure, 'push');
            app.BinarizeImageButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeImageButtonPushed, true);
            app.BinarizeImageButton.Text = 'Binarize Image';
            app.MorphologicalImageButton = uibutton(app.UIFigure, 'push');
            app.MorphologicalImageButton.ButtonPushedFcn = createCallbackFcn(app, @MorphologicalImageButtonPushed, true);
            app.MorphologicalImageButton.Text = 'Morphological Image';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.GenerateTextButton = uibutton(app.UIFigure, 'push');
            app.GenerateTextButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTextButtonPushed, true);
            app.GenerateTextButton.Text = 'Generate Text';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SymbolRecognizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end